classdef VolumeShow < matlab.ui.componentcontainer.ComponentContainer
    %VOLUMESHOW An example UI component for 3-D volume visualization with
    %synchronized colormap
    %   vs = VOLUMESHOW creates a VolumeShow component in a new figure
    %   and returns the VolumeShow component object. MATLAB calls the
    %   uifigure function to create the figure. The component displays
    %   volumetric data using a configurable rendering style and an
    %   automatically synchronized colorbar.
    %   
    %   vs = VOLUMESHOW(parent) creates a VolumeShow component in the
    %   specified parent container. The parent can be a Figure object or
    %   one of its child containers.
    %   
    %   vs = VOLUMESHOW(___,Name=Value) creates a VolumeShow component with
    %   properties specified by one or more name-value arguments. For
    %   example, specify the volume data colormap using the Colormap
    %   property. Use this option with any of the input argument
    %   combinations in the previous syntaxes.
    %   
    %   Example 1: Create a VolumeShow component
    %        vs = VolumeShow;
    %   
    %   Example 2: Create a VolumeShow component in a UI figure
    %        fig = uifigure;
    %        vs  = VolumeShow(fig);
    %    
    %   Example 3: Create a VolumeShow component in a UI figure, and
    %   customize its appearance by specifying property values.
    %        data = rand(128,128,64);
    %        fig = uifigure;
    %        vs = VolumeShow(fig, ...
    %               Position=[100 100 800 600], ...
    %               VolumeData=data, ...
    %               Colormap="hot", ...
    %               RenderingStyle="VolumeRendering");
    %    
    %   Copyright 2026 The MathWorks, Inc.

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        MainGridLayout  matlab.ui.container.GridLayout
        ColorbarAxes    matlab.ui.control.UIAxes
    end

    events
        SlicePlaneChanged
    end

    properties (Access = protected)
        % 3D viewer component for interactive volume visualization
        VolumeDisplay     images.ui.graphics.Viewer

        % Volume visualization object that renders the data
        VolumeImage       images.ui.graphics3d.Volume

        % Colorbar object for displaying data range and colors
        ColorbarHandle    matlab.graphics.illustration.ColorBar
    end

    properties (Access = private)
        % ---- Internal data storage ----

        % Internal storage for the 3D volume data
        VolumeDataInternal = []

        % Internal storage for the colormap (default: parula)
        ColormapInternal = parula(256)

        % Internal storage for rendering style (default: VolumeRendering)
        RenderingStyleInternal = "VolumeRendering"

        % ---- Dirty flags ----
        VolumeDataDirty logical     = false
        ColormapDirty logical       = false
        RenderingStyleDirty logical = false

        % Timer for polling slice plane changes
        SlicePlaneListener timer = timer.empty
        % Last known slice plane values for change detection
        LastSlicePlaneValues = []

        % ---- Built-in slice panel components ---- %
        SliceAxes matlab.ui.control.UIAxes                                    
        SliceImage                                                         
        SlicePanelVisible logical = false                                   
    end

    properties (Dependent)
        % VolumeData - 3D volumetric data to be visualized
        % Setting this property loads the volume and updates the display
        % The data should be a 3D numeric array
        VolumeData

        % Colormap - Color mapping for volume visualization
        % Can be specified as a colormap name (string) or N x 3 RGB matrix
        % Common options: "parula", "jet", "hot", "cool", "gray"
        Colormap

        % RenderingStyle - Method used for volume rendering
        % Options include: "Isosurface", "VolumeRendering",
        % "MaximumIntensityProjection", etc ...
        % Each style provides different visualization characteristics
        RenderingStyle
    end
    properties (Dependent, SetAccess = private)
        SlicePlaneValues
    end

    % ========================= PROPERTY ACCESS METHODS =========================
    methods
        %% Property Set and Get Methods

        function set.VolumeData(comp, data)
            % SET.VOLUMEDATA - Set method for the VolumeData property
            %
            % This method is called when the VolumeData property is set.
            % It validates and stores the new volume data, then triggers
            % a display update to reflect the changes.
            %
            % INPUTS:
            %   data - 3D numeric array containing volume data
            %          Can be any numeric type (double, single, int16, etc.)
            %
            % EXAMPLE:
            %   comp.VolumeData = rand(100, 100, 50);  % Set random volume

            % Check if data is numeric
            % Check if data is 3D
            if ~isnumeric(data) || ndims(data) ~= 3
                displayError(comp, "Invalid volume data, data should be 3D and numeric");
                return;
            end

            % Store the new volume data internally
            comp.VolumeDataInternal = data;
            comp.VolumeDataDirty = true;
        end

        function data = get.VolumeData(comp)
            % GET.VOLUMEDATA - Get method for the VolumeData property
            %
            % This method is called when the VolumeData property is accessed.
            % It returns the currently stored volume data.
            %
            % OUTPUTS:
            %   data - Currently stored 3D volume data array
            %          Returns empty array [] if no data is loaded
            %
            % EXAMPLE:
            %   currentData = comp.VolumeData;  % Retrieve current volume

            % Return the internally stored volume data
            data = comp.VolumeDataInternal;
        end

        function set.Colormap(comp, value)
            % SET.COLORMAP - Setter method for the Colormap property
            %
            % This method handles setting the colormap for volume visualization.
            % It accepts both string colormap names and direct colormap matrices,
            % providing flexibility in color scheme selection.
            %
            % INPUTS:
            %   value - Either:
            %           1) String/char colormap name ("jet", "hot", "parula", etc.)
            %           2) Nx3 matrix of RGB values (0-1 range)
            %
            % EXAMPLES:
            %   comp.Colormap = "jet";           % Use named colormap
            %   comp.Colormap = hot(256);        % Use colormap matrix
            %   comp.Colormap = customColors;    % Use custom Nx3 matrix

            % Store previous colormap to revert on error
            previousColormap = comp.ColormapInternal;

            % Handle string colormap names
            if ischar(value) || isstring(value)
                try
                    % Convert colormap name to actual colormap matrix
                    % Use 256 levels for smooth color transitions
                    comp.ColormapInternal = feval(value, 256);
                catch
                    % Handle invalid colormap names gracefully
                    comp.ColormapInternal = previousColormap;
                    displayError(comp, "'" + string(value) + "' is not a recognized colormap name. " + ...
                        "Use a valid colormap such as 'parula', 'jet', 'hot', 'cool', or 'gray'.");
                    return;
                end
            elseif isnumeric(value)
                if size(value, 2) ~= 3 || ~ismatrix(value)
                    comp.ColormapInternal = previousColormap;
                    displayError(comp, "Colormap must be an Nx3 matrix of RGB values. " + ...
                        "Each row should contain three values in the range [0, 1].");
                    return;
                end
                if any(value(:) < 0) || any(value(:) > 1)
                    comp.ColormapInternal = previousColormap;
                    displayError(comp, "Colormap RGB values must be in the range [0, 1].");
                    return;
                end
                comp.ColormapInternal = value;
            else
                comp.ColormapInternal = previousColormap;
                displayError(comp, "Colormap must be a colormap name (string) or an Nx3 numeric matrix of RGB values.");
                return;
            end

            % Update dirty flag and actual value will update in update
            % method
            comp.ColormapDirty = true;
        end

        function value = get.Colormap(comp)
            % GET.COLORMAP - Get method for the Colormap property
            %
            % This method returns the current colormap being used for
            % volume visualization.
            %
            % OUTPUTS:
            %   value - N x 3 matrix of RGB values representing the colormap
            %           Each row is an RGB triplet with values in [0,1]
            %
            % EXAMPLE:
            %   currentMap = comp.Colormap;  % Get current colormap

            % Return the current colormap matrix
            value = comp.ColormapInternal;
        end

        function set.RenderingStyle(comp, value)
            % SET.RENDERINGSTYLE - Setter method for the RenderingStyle property
            %
            % This method sets the rendering technique used for volume visualization.
            % Different styles offer different visualization characteristics:
            %   - "Isosurface": Shows surfaces of constant value
            %   - "VolumeRendering": Semi-transparent volume with depth
            %   - "MaximumIntensityProjection": Shows maximum values along rays
            %   - "MinimumIntensityProjection": Shows minimum values along rays
            %
            % INPUTS:
            %   value - String specifying the rendering style
            %
            % EXAMPLES:
            %   comp.RenderingStyle = "VolumeRendering";  % Semi-transparent
            %   comp.RenderingStyle = "Isosurface";       % Surface rendering


            validStyles = ["Isosurface","VolumeRendering", "CinematicRendering", ...
                "LightScattering", "GradientOpacity", "SlicePlanes", ...
                "MaximumIntensityProjection","MinimumIntensityProjection"];

            % Check for invalid rendering style and return
            if ~any(strcmpi(value, validStyles))
                displayError(comp, "Invalid RenderingStyle value. Supported styles: " + ...
                    strjoin(validStyles, ", ") + ".");

                return;
            end

            % Store the new rendering style
            comp.RenderingStyleInternal = value;

            % Update dirty flag and actual value will update in update
            % method
            comp.RenderingStyleDirty = true;
        end

        function value = get.RenderingStyle(comp)
            % GET.RENDERINGSTYLE - Get method for the RenderingStyle property
            %
            % This method returns the current rendering style being used
            % for volume visualization.
            %
            % OUTPUTS:
            %   value - String indicating the current rendering style
            %           Common values: "Isosurface", "VolumeRendering",
            %           "MaximumIntensityProjection", etc.
            %
            % EXAMPLE:
            %   currentStyle = comp.RenderingStyle;  % Get current style

            % Return the current rendering style
            value = comp.RenderingStyleInternal;
        end

        function val = get.SlicePlaneValues(comp)
            if ~isempty(comp.VolumeImage) && comp.RenderingStyleInternal == "SlicePlanes"
                val = comp.VolumeImage.SlicePlaneValues;
            else
                val = [];
            end
        end
    end

    % Helper functions
    methods(Access = private)
        function displayError(comp, errorMsg)
            % DISPLAYERROR - The error message is displayed as an alert
            % dialog if the component has a parent. Otherwise the error
            % message will appear in the Command Window.

            fig = ancestor(comp, 'figure');
            if isempty(fig)
                error(errorMsg);
            else
                uialert(fig,errorMsg,"Error");
            end
        end

        function checkSlicePlaneChange(comp)
            if ~isvalid(comp) || isempty(comp.VolumeImage) || comp.RenderingStyleInternal ~= "SlicePlanes" 
                return;
            end
            currentVals = comp.VolumeImage.SlicePlaneValues;
            if ~isequal(currentVals, comp.LastSlicePlaneValues)
                prevVals = comp.LastSlicePlaneValues;
                comp.LastSlicePlaneValues = currentVals;
                notify(comp, 'SlicePlaneChanged');
                updateSlicePanel(comp, currentVals, prevVals);
            end
        end

        function cleanupTimer(comp)
            if ~isempty(comp.SlicePlaneListener) && isvalid(comp.SlicePlaneListener)
                stop(comp.SlicePlaneListener);
                delete(comp.SlicePlaneListener);
            end
        end

        function showSlicePanel(comp)
            if isempty(comp.SliceAxes)
                return;
            end
            comp.MainGridLayout.ColumnWidth = {'6x', '1x', '4x'};
            comp.SliceAxes.Visible = 'on';
            comp.SlicePanelVisible = true;
            comp.LastSlicePlaneValues = [];

            % Restart timer for polling
            if ~isempty(comp.SlicePlaneListener) && isvalid(comp.SlicePlaneListener) ...
                     && strcmp(comp.SlicePlaneListener.Running, 'off')
                start(comp.SlicePlaneListener);
            end

            % Show initial Z slice
            if ~isempty(comp.VolumeDataInternal)
                D = comp.VolumeDataInternal;
                idx = ceil(size(D,3)/2);
                comp.SliceImage.CData = D(:,:,idx);
                comp.SliceAxes.Colormap  = comp.ColormapInternal;
                comp.SliceAxes.DataAspectRatio = [1 1 1];
                comp.SliceAxes.Title.String = "Z (Axial) — Slice " + idx;
                comp.SliceAxes.Title.Color = [0.8 0.8 0.8];
            end
        end

        function hideSlicePanel(comp)
            if isempty(comp.SliceAxes)
                return;
            end
            comp.MainGridLayout.ColumnWidth = {'9x', '1x', 0};
            comp.SliceAxes.Visible = 'off';
            comp.SlicePanelVisible = false;
            % Stop timer when not in SlicePlanes mode
            if ~isempty(comp.SlicePlaneListener) && isvalid(comp.SlicePlaneListener)
                stop(comp.SlicePlaneListener);
            end
        end

        function updateSlicePanel(comp, currVals, prevVals)
            if ~comp.SlicePanelVisible || isempty(comp.VolumeDataInternal)
                return;
            end
            D = comp.VolumeDataInternal;


            % On first call, just show Z slice
            if isempty(prevVals)
                idx = max(1, min(size(D,3), double(round(currVals(3,4)))));
                comp.SliceImage.CData = D(:,:,idx);
                comp.SliceAxes.Title.String = "Z (Axial) — Slice " + idx;
                comp.SliceAxes.Title.Color = [0.8 0.8 0.8];                
                return;
            end

            % Find which plane changed
            planeDiff = abs(double(currVals(:,4)) - double(prevVals(:,4)));
            [~, plane] = max(planeDiff);

            switch plane
                case 1  % X (Sagittal)
                    idx = max(1, min(size(D,1), double(round(currVals(1,4)))));
                    comp.SliceImage.CData = squeeze(D(idx,:,:))';
                    comp.SliceAxes.Title.String = "X (Sagittal) — Slice " + idx;

                case 2  % Y (Coronal)
                    idx = max(1, min(size(D,2), double(round(currVals(2,4)))));
                    comp.SliceImage.CData = squeeze(D(:,idx,:))';
                    comp.SliceAxes.Title.String = "Y (Coronal) — Slice " + idx;

                case 3  % Z (Axial)
                    idx = max(1, min(size(D,3), double(round(currVals(3,4)))));
                    comp.SliceImage.CData = D(:,:,idx);
                    comp.SliceAxes.Title.String = "Z (Axial) — Slice " + idx;

            end
            comp.SliceAxes.Title.Color = [0.8 0.8 0.8];
            comp.SliceAxes.DataAspectRatio = [1 1 1];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            % Create the main 3D viewer component
            % This provides interactive controls for rotation, zoom, and pan
            comp.VolumeDisplay = viewer3d(Parent=comp.MainGridLayout);
            comp.VolumeDisplay.Layout.Row = 1;
            comp.VolumeDisplay.Layout.Column = 1;

            % Configure dark theme for better volume visualization
            % Dark backgrounds reduce eye strain and improve contrast
            comp.VolumeDisplay.BackgroundColor = [0 0 0];      % Pure black background
            comp.VolumeDisplay.GradientColor = [0.2 0.2 0.2];  % Subtle gradient for depth

            % Prepare initial data for display
            % Use provided data or create minimal placeholder
            if ~isempty(comp.VolumeDataInternal)
                initialData = comp.VolumeDataInternal;
            else
                % Create small placeholder to avoid errors
                % 10x10x10 is minimal size for volshow
                initialData = zeros(10,10,10);
            end

            % Create the volume visualization object
            % This renders the 3D data using the specified style
            comp.VolumeImage = volshow(initialData, ...
                Parent=comp.VolumeDisplay, ...
                RenderingStyle=comp.RenderingStyleInternal, ...
                Colormap=comp.ColormapInternal);

            % Synchronize colormap between volume and colorbar
            % This ensures visual consistency
            comp.ColorbarAxes.Colormap = comp.VolumeImage.Colormap;

            % Calculate and set appropriate color limits
            % This maps data values to colors correctly
            if ~isempty(comp.VolumeDataInternal)
                dataRange = [min(comp.VolumeDataInternal(:)), max(comp.VolumeDataInternal(:))];
                if dataRange(1) ~= dataRange(2)  % Ensure valid range
                    comp.ColorbarAxes.CLim = dataRange;
                end
            end

            % Create the colorbar with appropriate styling
            % Light gray text provides good contrast on dark background
            comp.ColorbarHandle = colorbar(comp.ColorbarAxes);
            comp.ColorbarHandle.Color = [0.8 0.8 0.8];     % Light gray for visibility
            comp.ColorbarHandle.Location = "eastoutside";     % Right outside of axes

            comp.MainGridLayout.BackgroundColor = comp.VolumeDisplay.BackgroundColor;

            % ---- Create built-in slice panel ---- %

            comp.MainGridLayout.ColumnWidth = {'9x', '1x', 0};
            comp.SliceAxes = uiaxes(comp.MainGridLayout);
            comp.SliceAxes.Layout.Row = 1;
            comp.SliceAxes.Layout.Column = 3;
            comp.SliceAxes.Visible = 'off';
            comp.SliceAxes.Color = [0 0 0];
            comp.SliceAxes.XTick = [];
            comp.SliceAxes.YTick = [];
            % Create placeholder image
            comp.SliceImage = matlab.graphics.primitive.Image;
            comp.SliceImage.CData = zeros(10,10);
            comp.SliceImage.CDataMapping = 'scaled';
            comp.SliceImage.Parent = comp.SliceAxes;
            comp.SliceAxes.Colormap = comp.ColormapInternal;
            comp.SliceAxes.DataAspectRatio = [1 1 1];

            % Poll for slice plane changes using a timer
            comp.SlicePlaneListener = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 0.2, ...
                'TimerFcn', @(~,~) checkSlicePlaneChange(comp), ...
                'ErrorFcn', @(~,~) cleanupTimer(comp));

            if comp.RenderingStyleInternal == "SlicePlanes"
                start(comp.SlicePlaneListener);
            end

            % Clean up timer when component is deleted
            comp.DeleteFcn = @(~,~) cleanupTimer(comp);

        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % UPDATE - Refresh the display when properties change
            %
            % This method is automatically called when dependent properties
            % are modified. It updates the volume visualization, colorbar,
            % and ensures all components remain synchronized.
            %
            % The method handles:
            %   - Volume data updates with automatic range calculation
            %   - Colormap synchronization between volume and colorbar
            %   - Rendering style changes
            %
            % Safety checks prevent errors during initialization or when
            % components are not yet created.

            % Safety check - ensure components exist before updating
            if isempty(comp.VolumeImage)
                return;
            end

            % Update volume data only if it changed
            if comp.VolumeDataDirty && ~isempty(comp.VolumeDataInternal)
                % Apply new data to volume visualization
                comp.VolumeImage.Data = comp.VolumeDataInternal;

                % Recalculate data range for colorbar scaling
                % This ensures the full data range is visible
                dataRange = [min(comp.VolumeDataInternal(:)), max(comp.VolumeDataInternal(:))];
                if dataRange(1) ~= dataRange(2)
                    comp.ColorbarAxes.CLim = dataRange;
                end
                comp.VolumeDataDirty = false;
            end

            % Update colormap only if it changed
            if comp.ColormapDirty
                % Synchronize colormap between all components
                % This maintains visual consistency across the display
                comp.VolumeImage.Colormap = comp.ColormapInternal;
                comp.ColorbarAxes.Colormap = comp.ColormapInternal;
                if comp.SlicePanelVisible
                    comp.SliceAxes.Colormap = comp.ColormapInternal;
                end
                comp.ColormapDirty = false;
            end

            % Update rendering style only if it changed
            if comp.RenderingStyleDirty
                % Apply the selected rendering style
                % Different styles provide different visualization characteristics
                comp.RenderingStyleDirty = false;
                comp.VolumeImage.RenderingStyle = comp.RenderingStyleInternal;

                % Show/hide slice panel based on rendering style
                if comp.RenderingStyleInternal == "SlicePlanes"
                    showSlicePanel(comp);
                else
                    hideSlicePanel(comp);
                end
            end
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 728 495];
            comp.MainGridLayout = uigridlayout(comp);
            comp.ColorbarAxes = uiaxes(comp.MainGridLayout);
            comp.ColorbarAxes.Visible = 'off';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end